classdef BoaTico2Go < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DatosTab                        matlab.ui.container.Tab
        GraciasporinteresarseenadquirirnuestroproductoLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        maneramsefectivaPorfavorpodradecirnosLabel  matlab.ui.control.Label
        LargodelBotemEditFieldLabel     matlab.ui.control.Label
        largo                           matlab.ui.control.NumericEditField
        AnchodelBotemEditFieldLabel     matlab.ui.control.Label
        ancho                           matlab.ui.control.NumericEditField
        NombredelClienteLabel           matlab.ui.control.Label
        Usuario                         matlab.ui.control.EditField
        PesodelBotekgEditFieldLabel     matlab.ui.control.Label
        peso                            matlab.ui.control.NumericEditField
        VelocidadAngular                matlab.ui.control.Knob
        MaterialLabel                   matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        delLabel                        matlab.ui.control.Label
        MecanismoLabel                  matlab.ui.control.Label
        DisearmiMecanismoButton         matlab.ui.control.Button
        Switch                          matlab.ui.control.ToggleSwitch
        AcelAngular                     matlab.ui.control.Knob
        MecanismoTab                    matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        MaquetadelMecanismoLabel        matlab.ui.control.Label
        SimulacindelMecanismoLabel      matlab.ui.control.Label
        SealamientosdereferenciaLabel   matlab.ui.control.Label
        SetratadedosmecanismosTipo2quetransportanLabel  matlab.ui.control.Label
        A175deentradaeleslabon3estaenelmuelleLabel  matlab.ui.control.Label
        A350deentradaeleslabon3estaenelaguaLabel  matlab.ui.control.Label
        unbotedesdeelmuellehastaelaguaLabel  matlab.ui.control.Label
        IG2kgm2Label                    matlab.ui.control.Label
        InerciaOA                       matlab.ui.control.NumericEditField
        IG3kgm2Label                    matlab.ui.control.Label
        InerciaAB                       matlab.ui.control.NumericEditField
        IG4kgm2Label                    matlab.ui.control.Label
        InerciaBC                       matlab.ui.control.NumericEditField
        FuerzasTab                      matlab.ui.container.Tab
        DiseadoparaEditFieldLabel       matlab.ui.control.Label
        Diseadopara1                    matlab.ui.control.EditField
        AngulodeSpinnerLabel            matlab.ui.control.Label
        Angulo1                         matlab.ui.control.Spinner
        EntradaLabel                    matlab.ui.control.Label
        WBoteNEditFieldLabel            matlab.ui.control.Label
        WBote                           matlab.ui.control.NumericEditField
        WOANLabel                       matlab.ui.control.Label
        WOA                             matlab.ui.control.NumericEditField
        WABNLabel                       matlab.ui.control.Label
        WAB                             matlab.ui.control.NumericEditField
        WBCNLabel                       matlab.ui.control.Label
        WBC                             matlab.ui.control.NumericEditField
        OyNLabel                        matlab.ui.control.Label
        Oy                              matlab.ui.control.NumericEditField
        OxNLabel                        matlab.ui.control.Label
        Ox                              matlab.ui.control.NumericEditField
        ByNLabel                        matlab.ui.control.Label
        By                              matlab.ui.control.NumericEditField
        BxNLabel                        matlab.ui.control.Label
        Bx                              matlab.ui.control.NumericEditField
        AyNLabel                        matlab.ui.control.Label
        Ay                              matlab.ui.control.NumericEditField
        AxNLabel                        matlab.ui.control.Label
        Ax                              matlab.ui.control.NumericEditField
        CyNLabel                        matlab.ui.control.Label
        Cy                              matlab.ui.control.NumericEditField
        CxNLabel                        matlab.ui.control.Label
        Cx                              matlab.ui.control.NumericEditField
        EsfuerzosTab                    matlab.ui.container.Tab
        DiseadoparaEditField_2Label     matlab.ui.control.Label
        Diseadopara2                    matlab.ui.control.EditField
        AngulodeSpinner_2Label          matlab.ui.control.Label
        Angulo2                         matlab.ui.control.Spinner
        EntradaLabel_2                  matlab.ui.control.Label
        EsfuerzoOPaLabel                matlab.ui.control.Label
        EsfuerzoO                       matlab.ui.control.NumericEditField
        EsfuerzoAPaLabel                matlab.ui.control.Label
        EsfuerzoA                       matlab.ui.control.NumericEditField
        EsfuerzoBPaLabel                matlab.ui.control.Label
        EsfuerzoB                       matlab.ui.control.NumericEditField
        EsfuerzoCPaLabel                matlab.ui.control.Label
        EsfuerzoC                       matlab.ui.control.NumericEditField
        CortanteOPaEditFieldLabel       matlab.ui.control.Label
        CortanteO                       matlab.ui.control.NumericEditField
        CortanteAPaEditFieldLabel       matlab.ui.control.Label
        CortanteA                       matlab.ui.control.NumericEditField
        CortanteBPaEditFieldLabel       matlab.ui.control.Label
        CortanteB                       matlab.ui.control.NumericEditField
        CortanteCPaEditFieldLabel       matlab.ui.control.Label
        CortanteC                       matlab.ui.control.NumericEditField
        MomentoTab                      matlab.ui.container.Tab
        DiseadoparaEditField_3Label     matlab.ui.control.Label
        Diseadopara3                    matlab.ui.control.EditField
        AngulodeSpinner_3Label          matlab.ui.control.Label
        Angulo3                         matlab.ui.control.Spinner
        EntradaLabel_3                  matlab.ui.control.Label
        MomentodeEditFieldLabel         matlab.ui.control.Label
        MomentodeEntrada                matlab.ui.control.EditField
        EntradaNmLabel                  matlab.ui.control.Label
        MomentodeEntradaPlot            matlab.ui.control.UIAxes
        FSacudimientoTab                matlab.ui.container.Tab
        FO                              matlab.ui.control.UIAxes
        FC                              matlab.ui.control.UIAxes
        DiseadoparaEditField_4Label     matlab.ui.control.Label
        Diseadopara4                    matlab.ui.control.EditField
        SimplificacinTab                matlab.ui.container.Tab
        DiseadoparaEditField_4Label_2   matlab.ui.control.Label
        Diseadopara5                    matlab.ui.control.EditField
        Image5                          matlab.ui.control.Image
        Eslabon2Label                   matlab.ui.control.Label
        mokgEditFieldLabel              matlab.ui.control.Label
        mo                              matlab.ui.control.NumericEditField
        mA2kgEditFieldLabel             matlab.ui.control.Label
        mA2                             matlab.ui.control.NumericEditField
        IG2kgm2EditFieldLabel           matlab.ui.control.Label
        IG2P                            matlab.ui.control.NumericEditField
        errorEditFieldLabel             matlab.ui.control.Label
        error2                          matlab.ui.control.NumericEditField
        Eslabon3Label                   matlab.ui.control.Label
        mA3kgLabel                      matlab.ui.control.Label
        mA3                             matlab.ui.control.NumericEditField
        mB3kgLabel                      matlab.ui.control.Label
        mB3                             matlab.ui.control.NumericEditField
        IG3kgm2Label_2                  matlab.ui.control.Label
        IG3P                            matlab.ui.control.NumericEditField
        errorEditFieldLabel_2           matlab.ui.control.Label
        error3                          matlab.ui.control.NumericEditField
        Eslabon4Label                   matlab.ui.control.Label
        mckgLabel                       matlab.ui.control.Label
        mc                              matlab.ui.control.NumericEditField
        mB2kgLabel                      matlab.ui.control.Label
        mB2                             matlab.ui.control.NumericEditField
        IG4kgm2Label_2                  matlab.ui.control.Label
        IG4P                            matlab.ui.control.NumericEditField
        errorEditFieldLabel_3           matlab.ui.control.Label
        error4                          matlab.ui.control.NumericEditField
        FOB                             matlab.ui.control.UIAxes
        FCB                             matlab.ui.control.UIAxes
        FSacBalancTab                   matlab.ui.container.Tab
        DiseadoparaEditField_4Label_4   matlab.ui.control.Label
        MomentodeEntradaPlotB           matlab.ui.control.UIAxes
        Diseadopara7                    matlab.ui.control.EditField
        MomentoBalancTab                matlab.ui.container.Tab
        DiseadoparaEditField_4Label_5   matlab.ui.control.Label
        Diseadopara8                    matlab.ui.control.EditField
        MomentoEditFieldLabel           matlab.ui.control.Label
        MomentoEBalanceado              matlab.ui.control.NumericEditField
        deLabel                         matlab.ui.control.Label
        EntradaLabel_4                  matlab.ui.control.Label
        BalanceadoLabel                 matlab.ui.control.Label
        CostoTab                        matlab.ui.container.Tab
        DiseadoparaEditField_4Label_6   matlab.ui.control.Label
        Diseadopara9                    matlab.ui.control.EditField
        MasaTotalEditFieldLabel         matlab.ui.control.Label
        MasaTotalMecanismo              matlab.ui.control.NumericEditField
        delmaterialLabel                matlab.ui.control.Label
        paraelLabel                     matlab.ui.control.Label
        mecanismoLabel                  matlab.ui.control.Label
        CostodelMaterialEditFieldLabel  matlab.ui.control.Label
        CostodelMaterialColones         matlab.ui.control.NumericEditField
        delLabel_2                      matlab.ui.control.Label
        MecanismoLabel_2                matlab.ui.control.Label
        ColonesLabel                    matlab.ui.control.Label
        delLabel_3                      matlab.ui.control.Label
        MecanismoLabel_3                matlab.ui.control.Label
        ColoneskgLabel                  matlab.ui.control.Label
        CostodelMaterialEditField_2Label  matlab.ui.control.Label
        CostodelMaterialColKg           matlab.ui.control.NumericEditField
        BoaTico2GoTab                   matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        comoparasaladasegneltamaodelbotequeustednecesiteLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ParacualquierconsultacontactenosEstamosparaservirleLabel  matlab.ui.control.Label
        boatico2goNoExistecomLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        oldpath = addpath('./icons')
        
        L1=8.4;
        L2=8.424;
        L3=8.4;
        L4=8.424;
        m;
        m2;
        m3;
        m4;
        volumen1;
        volumen2;
        volumen3;
        volumen4;
        cita2;
        cita3=341.03;
        cita4;
        L;
        Ancho;
        w2;
        w4;
        AcelAngular2;
        AcelAngular4;
        rho;
        inerciaOA;
        inerciaAB;
        inerciaBC;
        cita2x= (175:1:350);
        cita4x=(175:1:350);
        VelCentro2x;
        VelCentro2y;
        VelCentro3x;
        VelCentro3y;
        VelCentro4x;
        VelCentro4y;
        AcelCentro2x;
        AcelCentro2y;
        AcelCentro3x;
        AcelCentro3y;
        AcelCentro4x;
        AcelCentro4y;
        DAx;
        DAy;
        DBx;
        DBy;
        Centroide2=4.2119;
        Centroide3Ax=4.8643;
        Centroide3Ay=0.8008;
        Centroide3Ox;
        Centroide3Oy;
        Centroide4=4.2119;
        FA;
        FB;
        FCE;
        FOE;
        FAb;
        FBb;
        FCb;
        FOb;
        
        Atrans=1.05*0.35;
        Anorm=0.35*0.35;


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.AcelAngular.Visible='off';
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            app.UIFigure.AutoResizeChildren = 'on';
        end

        % Button pushed function: DisearmiMecanismoButton
        function DisearmiMecanismoButtonPushed(app, event)
        syms Ox Oy Ax Ay Bx By M2 Cx Cy OxB OyB AxB AyB BxB ByB M2B CxB CyB
        
        largoValueChanged(app, event)
        anchoValueChanged(app, event)
        pesoValueChanged(app, event)
        Angulo1ValueChanged(app,event)
        Angulo2ValueChanged(app, event)
        Angulo3ValueChanged(app, event)
        MaterialDropDownValueChanged(app, event)
        WOAValueChanged(app, event)
        WABValueChanged(app, event)
        WBCValueChanged(app, event)
        VelocidadAngularValueChanged(app, event)
        AcelAngularValueChanged(app, event)

        
        app.rho=str2double(app.MaterialDropDown.Value);
        app.L=app.largo.Value;
        app.Ancho=app.ancho.Value;
        app.m=app.peso.Value;
        
        app.Diseadopara1.Value=app.Usuario.Value;
        app.Diseadopara2.Value=app.Usuario.Value;
        app.Diseadopara3.Value=app.Usuario.Value;
        app.Diseadopara4.Value=app.Usuario.Value;
        app.Diseadopara5.Value=app.Usuario.Value;
        app.Diseadopara7.Value=app.Usuario.Value;
        app.Diseadopara8.Value=app.Usuario.Value;
        app.Diseadopara9.Value=app.Usuario.Value;
        
        app.DAx=app.L2*cos(app.cita2*(pi/180));
        app.DAy=app.L2*sin(app.cita2*(pi/180));
        
        app.Centroide3Ox=app.Centroide3Ax+app.DAx;
        app.Centroide3Oy=app.Centroide3Ay+app.DAy;
        
        app.VelCentro2x=-1*app.w2*app.Centroide2*sin(app.cita2*(pi/180));
        app.VelCentro2y=app.w2*app.Centroide2*cos(app.cita2*(pi/180));
        app.VelCentro4x=-1*app.w2*app.Centroide4*sin(app.cita4*(pi/180));
        app.VelCentro4y=app.w2*app.Centroide4*cos(app.cita4*(pi/180));
        app.VelCentro3x=-1*app.w2*app.Centroide4*sin(app.cita4*(pi/180));
        app.VelCentro3y=app.w2*app.Centroide4*cos(app.cita4*(pi/180));
        
        app.AcelCentro2x=app.AcelAngular2*sin(app.cita2*(pi/180))-(app.w2^2)*cos(app.cita2*(pi/180))*app.Centroide2;
        app.AcelCentro2y=-1*app.AcelAngular2*cos(app.cita2*(pi/180))-(app.w2^2)*sin(app.cita2*(pi/180))*app.Centroide2;
        app.AcelCentro4x=app.AcelAngular4*sin(app.cita4*(pi/180))-(app.w4^2)*cos(app.cita4*(pi/180))*app.Centroide4;
        app.AcelCentro4y=-1*app.AcelAngular4*cos(app.cita4*(pi/180))-(app.w4^2)*sin(app.cita4*(pi/180))*app.Centroide4;
        app.AcelCentro3x=app.AcelAngular2*sin(app.cita2*(pi/180))-(app.w2^2)*cos(app.cita2*(pi/180))*app.L2;
        app.AcelCentro3y=-1*app.AcelAngular2*cos(app.cita2*(pi/180))-(app.w2^2)*sin(app.cita2*(pi/180))*app.L2;
        
        Eq1 = Ox - Ay - app.m2*sqrt((app.AcelCentro2x)^2+(app.AcelCentro2y)^2)*cos(app.cita2*(pi/180))==0;
        Eq2= Oy + Ay - app.WOA.Value-app.m2*sqrt((app.AcelCentro2x)^2+(app.AcelCentro2y)^2)*sin(app.cita2*(pi/180))==0;
        Eq3= M2 - Oy* app.Centroide2*cos(app.cita2*(pi/180)) + Ox*app.Centroide2*sin(app.cita2*(pi/180))+ Ay*app.Centroide2*cos(app.cita2*(pi/180)) + Ax*app.Centroide2*sin(app.cita2*(pi/180)) - app.InerciaOA.Value* app.AcelAngular2 ==0;
        Eq4= -Bx + Cx - app.m4*sqrt((app.AcelCentro4x)^2+(app.AcelCentro4y)^2)*cos(app.cita4*(pi/180))==0;
        Eq5= -By + Cy - app.WBC.Value-app.m4*sqrt((app.AcelCentro4x)^2+(app.AcelCentro4y)^2)*sin(app.cita4*(pi/180))== 0;
        Eq6= Cx* app.Centroide4*sin(app.cita4*(pi/180)) + Cy*app.Centroide4*cos(app.cita4*(pi/180)) + Bx*app.Centroide4*sin(app.cita4*(pi/180)) - By*app.Centroide4*cos(app.cita4*(pi/180)) - app.InerciaBC.Value* app.AcelAngular4 ==0;
        Eq7= Ax + Bx - app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*cos(app.cita2*(pi/180)) == 0;
        Eq8=-Ay + By - app.WBC.Value -(app.WBote.Value/2) - app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*sin(app.cita2*(pi/180)) == 0;
        Eq9= -(app.WBC.Value + (app.WBote.Value/2))*(app.Centroide3Ox-app.DAx) + By *(0.79198) + Bx* (2.8)==0;
        
        eqns = [Eq1,Eq2,Eq3,Eq4,Eq5,Eq6,Eq7,Eq8,Eq9];
        S = solve (eqns, [Ox,Oy,Ax,Ay,Bx,By,Cx,Cy,M2]);
        M2=double(S.M2);
        M2=num2str(M2);
        app.MomentodeEntrada.Value=M2;
        
        Oy=double(S.Oy);
        app.Oy.Value=Oy;
        Ox=double(S.Ox);
        app.Ox.Value=Ox;
        
        Ax=double(S.Ax);
        app.Ax.Value=Ax;
        Ay=double(S.Ay);
        app.Ay.Value=Ay;
        
        Bx=double(S.Bx);
        app.Bx.Value=Bx;
        By=double(S.By);
        app.By.Value=By;
        
        Cx=double(S.Cx);
        app.Cx.Value=Cx;
        Cy=double(S.Cy);
        app.Cy.Value=Cy;
        
        app.FA=sqrt((Ax)^2+(Ay)^2);
        app.FB=sqrt((Bx)^2+(By)^2);
        app.FCE=sqrt((Cx)^2+(Cy)^2);
        app.FOE=sqrt((Ox)^2+(Oy)^2);
        
        %Inicia parte de Balance
        W2Prima=2*app.WOA.Value;
        W4Prima=2*app.WBC.Value;
        I2Prima=2*app.InerciaOA.Value;
        I4Prima=2*app.InerciaBC.Value;
        
%         Ec1=AxB+OxB==0;
%         Ec2=AyB+OyB-W2Prima==0;
%         Ec3=M2B+AyB*app.L2*sin(app.cita2*(pi/180))-AxB*app.L2*cos(app.cita2*(pi/180))+I2Prima*app.AcelAngular2==0;
%         Ec4=BxB+CxB==0;
%         Ec5=ByB+CyB-W4Prima==0;
%         Ec6=I4Prima*app.AcelAngular4-ByB*app.L4*sin(app.cita4*(pi/180))-BxB*app.L4*cos(app.cita4*(pi/180))==0;
%         Ec7=-AxB-BxB-app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*cos(app.cita2*(pi/180))==0;
%         Ec8=-AyB-ByB-app.WAB.Value-app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*sin(app.cita2*(pi/180))==0;
%         Ec9=-app.WAB.Value*(app.Centroide3Ox-app.DAx)-ByB*(0.79198)-BxB*(2.8)==0;
%         
%         ecns = [Ec1,Ec2,Ec3,Ec4,Ec5,Ec6,Ec7,Ec8,Ec9];
%         SB = solve (ecns, [OxB,OyB,AxB,AyB,BxB,ByB,CxB,CyB,M2B]);
%         M2B=double(SB.M2B);
%         M2B=num2str(M2B);
%         app.MomentodeEntradaB.Value=M2B;
        
        %Termina parte de balance
        
        EsfuerzoOValueChanged(app, event)
        EsfuerzoAValueChanged(app, event)
        EsfuerzoBValueChanged(app, event)
        EsfuerzoCValueChanged(app, event)
        CortanteAValueChanged(app, event)
        CortanteOValueChanged(app, event)
        CortanteBValueChanged(app, event)
        CortanteCValueChanged(app, event)
        
        
         syms OxP OyP AyP AxP BxP ByP M2P CxP CyP OxPB OyPB AyPB AxPB BxPB ByPB M2PB CxPB CyPB
         
         M2Plot=zeros(1,numel(app.cita2x));
         FOPlot=zeros(1,numel(app.cita2x));
         FCPlot=zeros(1,numel(app.cita2x));
         M2PlotB=zeros(1,numel(app.cita2x));
         FOPlotB=zeros(1,numel(app.cita2x));
         FCPlotB=zeros(1,numel(app.cita2x));
         
        for j=1:numel(app.cita2x)

        app.DAx=app.L2.*cos(app.cita2x(j).*(pi/180));
        app.DAy=app.L2.*sin(app.cita2x(j).*(pi/180));
        
        app.Centroide3Ox=app.Centroide3Ax+app.DAx;
        app.Centroide3Oy=app.Centroide3Ay+app.DAy;
        
        app.VelCentro2x=-1.*app.w2.*app.Centroide2.*sin(app.cita2x(j).*(pi/180));
        app.VelCentro2y=app.w2.*app.Centroide2.*cos(app.cita2x(j).*(pi/180));
        app.VelCentro4x=-1.*app.w2.*app.Centroide4.*sin(app.cita2x(j).*(pi/180));
        app.VelCentro4y=app.w2.*app.Centroide4.*cos(app.cita2x(j).*(pi/180));
        app.VelCentro3x=-1.*app.w2.*app.Centroide4.*sin(app.cita2x(j).*(pi/180));
        app.VelCentro3y=app.w2.*app.Centroide4.*cos(app.cita2x(j).*(pi/180));
        
        app.AcelCentro2x=app.AcelAngular2.*sin(app.cita2x(j).*(pi/180))-(app.w2.^2).*cos(app.cita2x(j).*(pi/180)).*app.Centroide2;
        app.AcelCentro2y=-1.*app.AcelAngular2.*cos(app.cita2x(j).*(pi/180))-(app.w2.^2).*sin(app.cita2x(j).*(pi/180)).*app.Centroide2;
        app.AcelCentro4x=app.AcelAngular4.*sin(app.cita2x(j).*(pi/180))-(app.w4.^2).*cos(app.cita2x(j).*(pi/180)).*app.Centroide4;
        app.AcelCentro4y=-1.*app.AcelAngular4.*cos(app.cita2x(j).*(pi/180))-(app.w4.^2).*sin(app.cita2x(j).*(pi/180)).*app.Centroide4;
        app.AcelCentro3x=app.AcelAngular2.*sin(app.cita2x(j).*(pi/180))-(app.w2.^2).*cos(app.cita2x(j).*(pi/180)).*app.L2;
        app.AcelCentro3y=-1.*app.AcelAngular2.*cos(app.cita2x(j).*(pi/180))-(app.w2.^2).*sin(app.cita2x(j).*(pi/180)).*app.L2;
         
        Eq11 = OxP - AyP - app.m2.*sqrt((app.AcelCentro2x).^2+(app.AcelCentro2y).^2).*cos(app.cita2x(j).*(pi/180))==0;
        Eq21= OyP + AyP - app.WOA.Value-app.m2.*sqrt((app.AcelCentro2x).^2+(app.AcelCentro2y).^2).*sin(app.cita2x(j).*(pi/180))==0;
        Eq31= M2P - OyP.* app.Centroide2.*cos(app.cita2x(j).*(pi/180)) + OxP.*app.Centroide2.*sin(app.cita2x(j).*(pi/180))+ AyP.*app.Centroide2.*cos(app.cita2x(j).*(pi/180)) + AxP.*app.Centroide2.*sin(app.cita2x(j).*(pi/180)) - app.InerciaOA.Value.* app.AcelAngular2 ==0;
        Eq41= -BxP + CxP - app.m4.*sqrt((app.AcelCentro4x).^2+(app.AcelCentro4y).^2).*cos(app.cita2x(j).*(pi/180))==0;
        Eq51= -ByP + CyP - app.WBC.Value-app.m4.*sqrt((app.AcelCentro4x).^2+(app.AcelCentro4y).^2).*sin(app.cita2x(j).*(pi/180))== 0;
        Eq61= CxP.* app.Centroide4.*sin(app.cita2x(j).*(pi/180)) + CyP.*app.Centroide4.*cos(app.cita2x(j).*(pi/180)) + BxP.*app.Centroide4.*sin(app.cita2x(j).*(pi/180)) - ByP.*app.Centroide4.*cos(app.cita2x(j).*(pi/180)) - app.InerciaBC.Value.* app.AcelAngular4 ==0;
        Eq71= AxP + BxP - app.m3.*sqrt((app.AcelCentro3x).^2+(app.AcelCentro3y).^2).*cos(app.cita2x(j).*(pi/180)) == 0;
        Eq81=-AyP + ByP - app.WBC.Value -(app.WBote.Value/2) - app.m3.*sqrt((app.AcelCentro3x).^2+(app.AcelCentro3y).^2).*sin(app.cita2x(j).*(pi/180)) == 0;
        Eq91= -(app.WBC.Value + (app.WBote.Value/2)).*(app.Centroide3Ox-app.DAx) + ByP.*(0.79198) + BxP.* (2.8)==0;
        
        Ec11=AxPB+OxPB==0;
        Ec21=AyPB+OyPB-W2Prima==0;
        Ec31=M2PB+AyPB.*app.L2.*sin(app.cita2.*(pi/180))-AxPB.*app.L2.*cos(app.cita2.*(pi/180))+I2Prima.*app.AcelAngular2==0;
        Ec41=BxPB+CxPB==0;
        Ec51=ByPB+CyPB-W4Prima==0;
        Ec61=I4Prima.*app.AcelAngular4-ByPB.*app.L4.*sin(app.cita4.*(pi/180))-BxPB.*app.L4.*cos(app.cita4.*(pi/180))==0;
        Ec71=-AxPB-BxPB-app.m3.*sqrt((app.AcelCentro3x).^2+(app.AcelCentro3y).^2).*cos(app.cita2.*(pi/180))==0;
        Ec81=-AyPB-ByPB-app.WAB.Value-app.m3.*sqrt((app.AcelCentro3x).^2+(app.AcelCentro3y).^2).*sin(app.cita2.*(pi/180))==0;
        Ec91=-app.WAB.Value.*(app.Centroide3Ox-app.DAx)-ByPB.*(0.79198)-BxPB.*(2.8)==0;
        
        eqns = [Eq11,Eq21,Eq31,Eq41,Eq51,Eq61,Eq71,Eq81,Eq91];
        ecns = [Ec11,Ec21,Ec31,Ec41,Ec51,Ec61,Ec71,Ec81,Ec91];
        
        S1 = solve (eqns, [OxP,OyP,AxP,AyP,BxP,ByP,CxP,CyP,M2P]);
        S2 = solve (ecns, [OxPB,OyPB,AxPB,AyPB,BxPB,ByPB,CxPB,CyPB,M2PB]);
        
        M2Plot(j)=double(S1.M2P);
        M2PlotB(j)=double(S2.M2PB);
        FOPlot(j)=sqrt(double(S1.OxP))^2+(double(S1.OyP)^2);
        FOPlotB(j)=sqrt(double(S2.OxPB))^2+(double(S2.OyPB)^2);
        FCPlot(j)=sqrt(double(S1.CxP))^2+(double(S1.CyP)^2);
        FCPlotB(j)=sqrt(double(S2.CxPB))^2+(double(S2.CyPB)^2);
        
        end
                plot(app.MomentodeEntradaPlot,app.cita2x,M2Plot);
                plot(app.FO,app.cita2x,FOPlot);
                plot(app.FC,app.cita2x,FCPlot);
               
                %Inicia Parte de Balance
                plot(app.MomentodeEntradaPlotB,app.cita2x,M2PlotB);
                plot(app.FOB,app.cita2x,FOPlotB);
                plot(app.FCB,app.cita2x,FCPlotB);

                %Termina Parte de Balance
               

                moValueChanged(app, event)
                mA3ValueChanged(app, event)
                mcValueChanged(app, event)
                
                IG2PValueChanged(app, event)
                IG3PValueChanged(app, event)
                IG4PValueChanged(app, event)
                
                error2ValueChanged(app, event)
                error3ValueChanged(app, event)
                error4ValueChanged(app, event)
                
                MasaTotalMecanismoValueChanged(app, event)
                CostodelMaterialColonesValueChanged(app, event)
 

        end

        % Value changed function: VelocidadAngular
        function VelocidadAngularValueChanged(app, event)
        
        app.AcelAngular.Visible='off';
        
        if isequal (app.VelocidadAngular.Visible,'on') 
        app.w2=-1*(app.VelocidadAngular.Value*((6.28/1)*(1/60)));
        app.w4=app.w2;
        %app.AcelAngular.Value=0;
        app.AcelAngular2=0;
        app.AcelAngular4=app.AcelAngular2;
        
        else
            app.w2=-1*sqrt(2*app.AcelAngular.Value*(app.cita2*(pi/180))+(0)^2);
            app.w4=app.w2;
            app.AcelAngular2=-1*app.AcelAngular.Value;
            app.AcelAngular4=app.AcelAngular2;
        end
        
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
        
            value = str2double(app.MaterialDropDown.Value);
            app.rho= str2double(app.MaterialDropDown.Value);
                      
            app.volumen2=8.4238*0.7*1.05;
            app.volumen3=0.00004742102*(70^3);
            app.volumen4=8.4238*0.7*1.05;
            app.InerciaOA.Value = (1/12)*app.rho*app.volumen2*((8.4238)^2)+((1.05)^2);
            app.inerciaOA=app.InerciaOA.Value;
            app.InerciaBC.Value = (1/12)*app.rho*app.volumen4*((8.4238)^2)+((1.05)^2);
            app.inerciaBC=app.InerciaBC.Value;
            app.m2=app.rho*app.volumen2;
            app.m4=app.rho*app.volumen2;
            app.m3=app.volumen3*app.rho;
            
            if isequal (value,350)
                app.InerciaAB.Value=0.00002360804*(70^6);
                app.inerciaAB=app.InerciaAB.Value;
                app.CostodelMaterialColKg.Value=21.459;
            end
            
            if isequal (value,0)
                app.InerciaAB.Value=0;
                app.InerciaOA.Value=0;
                app.InerciaBC.Value=0;
                app.inerciaOA=app.InerciaOA.Value;
                app.inerciaBC=app.InerciaBC.Value;
                app.inerciaAB=app.InerciaAB.Value;
                app.CostodelMaterialColKg.Value=0;
                
            end
            if isequal (value,560)
                app.InerciaAB.Value=0.00003888383*(70^6);
                app.inerciaAB=app.InerciaAB.Value;
                app.CostodelMaterialColKg.Value=90.85;
                
            end
            if isequal (value,8500)
                app.InerciaAB.Value=0.000590201*(70^6);
                app.inerciaAB=app.InerciaAB.Value;
                app.CostodelMaterialColKg.Value=4655.41;
            end  
            if isequal (value,7900)
                app.InerciaAB.Value=0.0005485398*(70^6);
                app.inerciaAB=app.InerciaAB.Value;
                app.CostodelMaterialColKg.Value=962.19;
            end  
            if isequal (value,2700)
                app.InerciaAB.Value=0.0001874756*(70^6);
                app.inerciaAB=app.InerciaAB.Value;
                app.CostodelMaterialColKg.Value=1108.44;
            end
        end

        % Value changed function: largo
        function largoValueChanged(app, event)

            app.L = app.largo.Value;
            
        end

        % Value changed function: ancho
        function anchoValueChanged(app, event)
        
            app.Ancho = app.ancho.Value;
            
            
        end

        % Value changed function: peso
        function pesoValueChanged(app, event)
        
            app.m = app.peso.Value;
            app.WBote.Value=app.m * 9.81;

        end

        % Value changed function: Usuario
        function UsuarioValueChanged(app, event)
        app.Diseadopara1.Value=app.Usuario.Value;
        app.Diseadopara2.Value=app.Usuario.Value;
        app.Diseadopara3.Value=app.Usuario.Value;
        app.Diseadopara4.Value=app.Usuario.Value;
        app.Diseadopara5.Value=app.Usuario.Value;
        %app.Diseadopara6.Value=app.Usuario.Value;
        app.Diseadopara7.Value=app.Usuario.Value;
        app.Diseadopara8.Value=app.Usuario.Value;
        app.Diseadopara9.Value=app.Usuario.Value;
        end

        % Value changed function: Angulo1
        function Angulo1ValueChanged(app, event)
        
        app.Angulo2.Value=app.Angulo1.Value;
        app.Angulo3.Value=app.Angulo1.Value;
%         app.Angulo3B.Value=app.Angulo1.Value;
        
        app.cita2=app.Angulo1.Value;
        app.cita4=app.cita2;
        
        syms Ox Oy Ax Ay Bx By M2 Cx Cy OxB OyB AxB AyB BxB ByB M2B CxB CyB
        
        largoValueChanged(app, event)
        anchoValueChanged(app, event)
        pesoValueChanged(app, event)
        MaterialDropDownValueChanged(app, event)
        WOAValueChanged(app, event)
        WABValueChanged(app, event)
        WBCValueChanged(app, event)
        VelocidadAngularValueChanged(app, event)
        AcelAngularValueChanged(app, event)

        
        app.rho=str2double(app.MaterialDropDown.Value);
        app.L=app.largo.Value;
        app.Ancho=app.ancho.Value;
        app.m=app.peso.Value;
        
        app.Diseadopara1.Value=app.Usuario.Value;
        app.Diseadopara2.Value=app.Usuario.Value;
        app.Diseadopara3.Value=app.Usuario.Value;
        app.Diseadopara4.Value=app.Usuario.Value;
        
        app.DAx=app.L2*cos(app.cita2*(pi/180));
        app.DAy=app.L2*sin(app.cita2*(pi/180));
        
        app.Centroide3Ox=app.Centroide3Ax+app.DAx;
        app.Centroide3Oy=app.Centroide3Ay+app.DAy;
        
        app.VelCentro2x=-1*app.w2*app.Centroide2*sin(app.cita2*(pi/180));
        app.VelCentro2y=app.w2*app.Centroide2*cos(app.cita2*(pi/180));
        app.VelCentro4x=-1*app.w2*app.Centroide4*sin(app.cita4*(pi/180));
        app.VelCentro4y=app.w2*app.Centroide4*cos(app.cita4*(pi/180));
        app.VelCentro3x=-1*app.w2*app.Centroide4*sin(app.cita4*(pi/180));
        app.VelCentro3y=app.w2*app.Centroide4*cos(app.cita4*(pi/180));
        
        app.AcelCentro2x=app.AcelAngular2*sin(app.cita2*(pi/180))-(app.w2^2)*cos(app.cita2*(pi/180))*app.Centroide2;
        app.AcelCentro2y=-1*app.AcelAngular2*cos(app.cita2*(pi/180))-(app.w2^2)*sin(app.cita2*(pi/180))*app.Centroide2;
        app.AcelCentro4x=app.AcelAngular4*sin(app.cita4*(pi/180))-(app.w4^2)*cos(app.cita4*(pi/180))*app.Centroide4;
        app.AcelCentro4y=-1*app.AcelAngular4*cos(app.cita4*(pi/180))-(app.w4^2)*sin(app.cita4*(pi/180))*app.Centroide4;
        app.AcelCentro3x=app.AcelAngular2*sin(app.cita2*(pi/180))-(app.w2^2)*cos(app.cita2*(pi/180))*app.L2;
        app.AcelCentro3y=-1*app.AcelAngular2*cos(app.cita2*(pi/180))-(app.w2^2)*sin(app.cita2*(pi/180))*app.L2;
        
        Eq1 = Ox - Ay - app.m2*sqrt((app.AcelCentro2x)^2+(app.AcelCentro2y)^2)*cos(app.cita2*(pi/180))==0;
        Eq2= Oy + Ay - app.WOA.Value-app.m2*sqrt((app.AcelCentro2x)^2+(app.AcelCentro2y)^2)*sin(app.cita2*(pi/180))==0;
        Eq3= M2 - Oy* app.Centroide2*cos(app.cita2*(pi/180)) + Ox*app.Centroide2*sin(app.cita2*(pi/180))+ Ay*app.Centroide2*cos(app.cita2*(pi/180)) + Ax*app.Centroide2*sin(app.cita2*(pi/180)) - app.InerciaOA.Value* app.AcelAngular2 ==0;
        Eq4= -Bx + Cx - app.m4*sqrt((app.AcelCentro4x)^2+(app.AcelCentro4y)^2)*cos(app.cita4*(pi/180))==0;
        Eq5= -By + Cy - app.WBC.Value-app.m4*sqrt((app.AcelCentro4x)^2+(app.AcelCentro4y)^2)*sin(app.cita4*(pi/180))== 0;
        Eq6= Cx* app.Centroide4*sin(app.cita4*(pi/180)) + Cy*app.Centroide4*cos(app.cita4*(pi/180)) + Bx*app.Centroide4*sin(app.cita4*(pi/180)) - By*app.Centroide4*cos(app.cita4*(pi/180)) - app.InerciaBC.Value* app.AcelAngular4 ==0;
        Eq7= Ax + Bx - app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*cos(app.cita2*(pi/180)) == 0;
        Eq8=-Ay + By - app.WAB.Value -(app.WBote.Value/2) - app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*sin(app.cita2*(pi/180)) == 0;
        Eq9= -(app.WAB.Value + (app.WBote.Value/2))*(app.Centroide3Ox-app.DAx) + By *(0.79198) + Bx* (2.8)==0;
        
        eqns = [Eq1,Eq2,Eq3,Eq4,Eq5,Eq6,Eq7,Eq8,Eq9];
        S = solve (eqns, [Ox,Oy,Ax,Ay,Bx,By,Cx,Cy,M2]);
        M2=double(S.M2);
        M2=num2str(M2);
        app.MomentodeEntrada.Value=M2;
        
        Oy=double(S.Oy);
        app.Oy.Value=Oy;
        Ox=double(S.Ox);
        app.Ox.Value=Ox;
        
        Ax=double(S.Ax);
        app.Ax.Value=Ax;
        Ay=double(S.Ay);
        app.Ay.Value=Ay;
        
        Bx=double(S.Bx);
        app.Bx.Value=Bx;
        By=double(S.By);
        app.By.Value=By;
        
        Cx=double(S.Cx);
        app.Cx.Value=Cx;
        Cy=double(S.Cy);
        app.Cy.Value=Cy;
        
        app.FA=sqrt((Ax)^2+(Ay)^2);
        app.FB=sqrt((Bx)^2+(By)^2);
        app.FCE=sqrt((Cx)^2+(Cy)^2);
        app.FOE=sqrt((Ox)^2+(Oy)^2);
        
        %Inicia parte de Balance
        W2Prima=2*app.WOA.Value;
        W4Prima=2*app.WBC.Value;
        I2Prima=2*app.InerciaOA.Value;
        I4Prima=2*app.InerciaBC.Value;
        
%         Ec1=AxB+OxB==0;
%         Ec2=AyB+OyB-W2Prima==0;
%         Ec3=M2B+AyB*app.L2*sin(app.cita2*(pi/180))-AxB*app.L2*cos(app.cita2*(pi/180))+I2Prima*app.AcelAngular2==0;
%         Ec4=BxB+CxB==0;
%         Ec5=ByB+CyB-W4Prima==0;
%         Ec6=I4Prima*app.AcelAngular4-ByB*app.L4*sin(app.cita4*(pi/180))-BxB*app.L4*cos(app.cita4*(pi/180))==0;
%         Ec7=-AxB-BxB-app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*cos(app.cita2*(pi/180))==0;
%         Ec8=-AyB-ByB-app.WAB.Value-app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*sin(app.cita2*(pi/180))==0;
%         Ec9=-app.WAB.Value*(app.Centroide3Ox-app.DAx)-ByB*(0.79198)-BxB*(2.8)==0;
%         
%         ecns = [Ec1,Ec2,Ec3,Ec4,Ec5,Ec6,Ec7,Ec8,Ec9];
%         SB = solve (ecns, [OxB,OyB,AxB,AyB,BxB,ByB,CxB,CyB,M2B]);
%         M2B=double(SB.M2B);
%         M2B=num2str(M2B);
%         app.MomentodeEntradaB.Value=M2B;
        
        %Termina parte de balance
        
        EsfuerzoOValueChanged(app, event)
        EsfuerzoAValueChanged(app, event)
        EsfuerzoBValueChanged(app, event)
        EsfuerzoCValueChanged(app, event)
        CortanteAValueChanged(app, event)
        CortanteOValueChanged(app, event)
        CortanteBValueChanged(app, event)
        CortanteCValueChanged(app, event)
        
        
        end

        % Value changed function: Angulo2
        function Angulo2ValueChanged(app, event)
        app.Angulo1.Value=app.Angulo2.Value;
        app.Angulo3.Value=app.Angulo2.Value;
%         app.Angulo3B.Value=app.Angulo2.Value;
        
        app.cita2=app.Angulo2.Value;
        app.cita4=app.cita2;
        
        syms Ox Oy Ax Ay Bx By M2 Cx Cy OxB OyB AxB AyB BxB ByB M2B CxB CyB
        
        largoValueChanged(app, event)
        anchoValueChanged(app, event)
        pesoValueChanged(app, event)
        MaterialDropDownValueChanged(app, event)
        WOAValueChanged(app, event)
        WABValueChanged(app, event)
        WBCValueChanged(app, event)
        VelocidadAngularValueChanged(app, event)
        AcelAngularValueChanged(app, event)

        
        app.rho=str2double(app.MaterialDropDown.Value);
        app.L=app.largo.Value;
        app.Ancho=app.ancho.Value;
        app.m=app.peso.Value;
        
        app.Diseadopara1.Value=app.Usuario.Value;
        app.Diseadopara2.Value=app.Usuario.Value;
        app.Diseadopara3.Value=app.Usuario.Value;
        app.Diseadopara4.Value=app.Usuario.Value;
        
        app.DAx=app.L2*cos(app.cita2*(pi/180));
        app.DAy=app.L2*sin(app.cita2*(pi/180));
        
        app.Centroide3Ox=app.Centroide3Ax+app.DAx;
        app.Centroide3Oy=app.Centroide3Ay+app.DAy;
        
        app.VelCentro2x=-1*app.w2*app.Centroide2*sin(app.cita2*(pi/180));
        app.VelCentro2y=app.w2*app.Centroide2*cos(app.cita2*(pi/180));
        app.VelCentro4x=-1*app.w2*app.Centroide4*sin(app.cita4*(pi/180));
        app.VelCentro4y=app.w2*app.Centroide4*cos(app.cita4*(pi/180));
        app.VelCentro3x=-1*app.w2*app.Centroide4*sin(app.cita4*(pi/180));
        app.VelCentro3y=app.w2*app.Centroide4*cos(app.cita4*(pi/180));
        
        app.AcelCentro2x=app.AcelAngular2*sin(app.cita2*(pi/180))-(app.w2^2)*cos(app.cita2*(pi/180))*app.Centroide2;
        app.AcelCentro2y=-1*app.AcelAngular2*cos(app.cita2*(pi/180))-(app.w2^2)*sin(app.cita2*(pi/180))*app.Centroide2;
        app.AcelCentro4x=app.AcelAngular4*sin(app.cita4*(pi/180))-(app.w4^2)*cos(app.cita4*(pi/180))*app.Centroide4;
        app.AcelCentro4y=-1*app.AcelAngular4*cos(app.cita4*(pi/180))-(app.w4^2)*sin(app.cita4*(pi/180))*app.Centroide4;
        app.AcelCentro3x=app.AcelAngular2*sin(app.cita2*(pi/180))-(app.w2^2)*cos(app.cita2*(pi/180))*app.L2;
        app.AcelCentro3y=-1*app.AcelAngular2*cos(app.cita2*(pi/180))-(app.w2^2)*sin(app.cita2*(pi/180))*app.L2;
        
        Eq1 = Ox - Ay - app.m2*sqrt((app.AcelCentro2x)^2+(app.AcelCentro2y)^2)*cos(app.cita2*(pi/180))==0;
        Eq2= Oy + Ay - app.WOA.Value-app.m2*sqrt((app.AcelCentro2x)^2+(app.AcelCentro2y)^2)*sin(app.cita2*(pi/180))==0;
        Eq3= M2 - Oy* app.Centroide2*cos(app.cita2*(pi/180)) + Ox*app.Centroide2*sin(app.cita2*(pi/180))+ Ay*app.Centroide2*cos(app.cita2*(pi/180)) + Ax*app.Centroide2*sin(app.cita2*(pi/180)) - app.InerciaOA.Value* app.AcelAngular2 ==0;
        Eq4= -Bx + Cx - app.m4*sqrt((app.AcelCentro4x)^2+(app.AcelCentro4y)^2)*cos(app.cita4*(pi/180))==0;
        Eq5= -By + Cy - app.WBC.Value-app.m4*sqrt((app.AcelCentro4x)^2+(app.AcelCentro4y)^2)*sin(app.cita4*(pi/180))== 0;
        Eq6= Cx* app.Centroide4*sin(app.cita4*(pi/180)) + Cy*app.Centroide4*cos(app.cita4*(pi/180)) + Bx*app.Centroide4*sin(app.cita4*(pi/180)) - By*app.Centroide4*cos(app.cita4*(pi/180)) - app.InerciaBC.Value* app.AcelAngular4 ==0;
        Eq7= Ax + Bx - app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*cos(app.cita2*(pi/180)) == 0;
        Eq8=-Ay + By - app.WAB.Value -(app.WBote.Value/2) - app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*sin(app.cita2*(pi/180)) == 0;
        Eq9= -(app.WAB.Value + (app.WBote.Value/2))*(app.Centroide3Ox-app.DAx) + By *(0.79198) + Bx* (2.8)==0;
        
        eqns = [Eq1,Eq2,Eq3,Eq4,Eq5,Eq6,Eq7,Eq8,Eq9];
        S = solve (eqns, [Ox,Oy,Ax,Ay,Bx,By,Cx,Cy,M2]);
        M2=double(S.M2);
        M2=num2str(M2);
        app.MomentodeEntrada.Value=M2;
        
        Oy=double(S.Oy);
        app.Oy.Value=Oy;
        Ox=double(S.Ox);
        app.Ox.Value=Ox;
        
        Ax=double(S.Ax);
        app.Ax.Value=Ax;
        Ay=double(S.Ay);
        app.Ay.Value=Ay;
        
        Bx=double(S.Bx);
        app.Bx.Value=Bx;
        By=double(S.By);
        app.By.Value=By;
        
        Cx=double(S.Cx);
        app.Cx.Value=Cx;
        Cy=double(S.Cy);
        app.Cy.Value=Cy;
        
        app.FA=sqrt((Ax)^2+(Ay)^2);
        app.FB=sqrt((Bx)^2+(By)^2);
        app.FCE=sqrt((Cx)^2+(Cy)^2);
        app.FOE=sqrt((Ox)^2+(Oy)^2);
        
        %Inicia parte de Balance
        W2Prima=2*app.WOA.Value;
        W4Prima=2*app.WBC.Value;
        I2Prima=2*app.InerciaOA.Value;
        I4Prima=2*app.InerciaBC.Value;
        
%         Ec1=AxB+OxB==0;
%         Ec2=AyB+OyB-W2Prima==0;
%         Ec3=M2B+AyB*app.L2*sin(app.cita2*(pi/180))-AxB*app.L2*cos(app.cita2*(pi/180))+I2Prima*app.AcelAngular2==0;
%         Ec4=BxB+CxB==0;
%         Ec5=ByB+CyB-W4Prima==0;
%         Ec6=I4Prima*app.AcelAngular4-ByB*app.L4*sin(app.cita4*(pi/180))-BxB*app.L4*cos(app.cita4*(pi/180))==0;
%         Ec7=-AxB-BxB-app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*cos(app.cita2*(pi/180))==0;
%         Ec8=-AyB-ByB-app.WAB.Value-app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*sin(app.cita2*(pi/180))==0;
%         Ec9=-app.WAB.Value*(app.Centroide3Ox-app.DAx)-ByB*(0.79198)-BxB*(2.8)==0;
%         
%         ecns = [Ec1,Ec2,Ec3,Ec4,Ec5,Ec6,Ec7,Ec8,Ec9];
%         SB = solve (ecns, [OxB,OyB,AxB,AyB,BxB,ByB,CxB,CyB,M2B]);
%         M2B=double(SB.M2B);
%         M2B=num2str(M2B);
%         app.MomentodeEntradaB.Value=M2B;
        
        %Termina parte de balance
        
        EsfuerzoOValueChanged(app, event)
        EsfuerzoAValueChanged(app, event)
        EsfuerzoBValueChanged(app, event)
        EsfuerzoCValueChanged(app, event)
        CortanteAValueChanged(app, event)
        CortanteOValueChanged(app, event)
        CortanteBValueChanged(app, event)
        CortanteCValueChanged(app, event)
            
        end

        % Value changed function: Angulo3
        function Angulo3ValueChanged(app, event)
        
       app.Angulo1.Value=app.Angulo3.Value;
        app.Angulo2.Value=app.Angulo3.Value;
%         app.Angulo3B.Value=app.Angulo3.Value;
        
        app.cita2=app.Angulo3.Value;
        app.cita4=app.cita2;
        
        syms Ox Oy Ax Ay Bx By M2 Cx Cy OxB OyB AxB AyB BxB ByB M2B CxB CyB
        
        largoValueChanged(app, event)
        anchoValueChanged(app, event)
        pesoValueChanged(app, event)
        MaterialDropDownValueChanged(app, event)
        WOAValueChanged(app, event)
        WABValueChanged(app, event)
        WBCValueChanged(app, event)
        VelocidadAngularValueChanged(app, event)
        AcelAngularValueChanged(app, event)

        
        app.rho=str2double(app.MaterialDropDown.Value);
        app.L=app.largo.Value;
        app.Ancho=app.ancho.Value;
        app.m=app.peso.Value;
        
        app.Diseadopara1.Value=app.Usuario.Value;
        app.Diseadopara2.Value=app.Usuario.Value;
        app.Diseadopara3.Value=app.Usuario.Value;
        app.Diseadopara4.Value=app.Usuario.Value;
        
        app.DAx=app.L2*cos(app.cita2*(pi/180));
        app.DAy=app.L2*sin(app.cita2*(pi/180));
        
        app.Centroide3Ox=app.Centroide3Ax+app.DAx;
        app.Centroide3Oy=app.Centroide3Ay+app.DAy;
        
        app.VelCentro2x=-1*app.w2*app.Centroide2*sin(app.cita2*(pi/180));
        app.VelCentro2y=app.w2*app.Centroide2*cos(app.cita2*(pi/180));
        app.VelCentro4x=-1*app.w2*app.Centroide4*sin(app.cita4*(pi/180));
        app.VelCentro4y=app.w2*app.Centroide4*cos(app.cita4*(pi/180));
        app.VelCentro3x=-1*app.w2*app.Centroide4*sin(app.cita4*(pi/180));
        app.VelCentro3y=app.w2*app.Centroide4*cos(app.cita4*(pi/180));
        
        app.AcelCentro2x=app.AcelAngular2*sin(app.cita2*(pi/180))-(app.w2^2)*cos(app.cita2*(pi/180))*app.Centroide2;
        app.AcelCentro2y=-1*app.AcelAngular2*cos(app.cita2*(pi/180))-(app.w2^2)*sin(app.cita2*(pi/180))*app.Centroide2;
        app.AcelCentro4x=app.AcelAngular4*sin(app.cita4*(pi/180))-(app.w4^2)*cos(app.cita4*(pi/180))*app.Centroide4;
        app.AcelCentro4y=-1*app.AcelAngular4*cos(app.cita4*(pi/180))-(app.w4^2)*sin(app.cita4*(pi/180))*app.Centroide4;
        app.AcelCentro3x=app.AcelAngular2*sin(app.cita2*(pi/180))-(app.w2^2)*cos(app.cita2*(pi/180))*app.L2;
        app.AcelCentro3y=-1*app.AcelAngular2*cos(app.cita2*(pi/180))-(app.w2^2)*sin(app.cita2*(pi/180))*app.L2;
        
        Eq1 = Ox - Ay - app.m2*sqrt((app.AcelCentro2x)^2+(app.AcelCentro2y)^2)*cos(app.cita2*(pi/180))==0;
        Eq2= Oy + Ay - app.WOA.Value-app.m2*sqrt((app.AcelCentro2x)^2+(app.AcelCentro2y)^2)*sin(app.cita2*(pi/180))==0;
        Eq3= M2 - Oy* app.Centroide2*cos(app.cita2*(pi/180)) + Ox*app.Centroide2*sin(app.cita2*(pi/180))+ Ay*app.Centroide2*cos(app.cita2*(pi/180)) + Ax*app.Centroide2*sin(app.cita2*(pi/180)) - app.InerciaOA.Value* app.AcelAngular2 ==0;
        Eq4= -Bx + Cx - app.m4*sqrt((app.AcelCentro4x)^2+(app.AcelCentro4y)^2)*cos(app.cita4*(pi/180))==0;
        Eq5= -By + Cy - app.WBC.Value-app.m4*sqrt((app.AcelCentro4x)^2+(app.AcelCentro4y)^2)*sin(app.cita4*(pi/180))== 0;
        Eq6= Cx* app.Centroide4*sin(app.cita4*(pi/180)) + Cy*app.Centroide4*cos(app.cita4*(pi/180)) + Bx*app.Centroide4*sin(app.cita4*(pi/180)) - By*app.Centroide4*cos(app.cita4*(pi/180)) - app.InerciaBC.Value* app.AcelAngular4 ==0;
        Eq7= Ax + Bx - app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*cos(app.cita2*(pi/180)) == 0;
        Eq8=-Ay + By - app.WAB.Value -(app.WBote.Value/2) - app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*sin(app.cita2*(pi/180)) == 0;
        Eq9= -(app.WAB.Value + (app.WBote.Value/2))*(app.Centroide3Ox-app.DAx) + By *(0.79198) + Bx* (2.8)==0;
        
        eqns = [Eq1,Eq2,Eq3,Eq4,Eq5,Eq6,Eq7,Eq8,Eq9];
        S = solve (eqns, [Ox,Oy,Ax,Ay,Bx,By,Cx,Cy,M2]);
        M2=double(S.M2);
        M2=num2str(M2);
        app.MomentodeEntrada.Value=M2;
        
        Oy=double(S.Oy);
        app.Oy.Value=Oy;
        Ox=double(S.Ox);
        app.Ox.Value=Ox;
        
        Ax=double(S.Ax);
        app.Ax.Value=Ax;
        Ay=double(S.Ay);
        app.Ay.Value=Ay;
        
        Bx=double(S.Bx);
        app.Bx.Value=Bx;
        By=double(S.By);
        app.By.Value=By;
        
        Cx=double(S.Cx);
        app.Cx.Value=Cx;
        Cy=double(S.Cy);
        app.Cy.Value=Cy;
        
        app.FA=sqrt((Ax)^2+(Ay)^2);
        app.FB=sqrt((Bx)^2+(By)^2);
        app.FCE=sqrt((Cx)^2+(Cy)^2);
        app.FOE=sqrt((Ox)^2+(Oy)^2);
        
        %Inicia parte de Balance
        W2Prima=2*app.WOA.Value;
        W4Prima=2*app.WBC.Value;
        I2Prima=2*app.InerciaOA.Value;
        I4Prima=2*app.InerciaBC.Value;
        
%         Ec1=AxB+OxB==0;
%         Ec2=AyB+OyB-W2Prima==0;
%         Ec3=M2B+AyB*app.L2*sin(app.cita2*(pi/180))-AxB*app.L2*cos(app.cita2*(pi/180))+I2Prima*app.AcelAngular2==0;
%         Ec4=BxB+CxB==0;
%         Ec5=ByB+CyB-W4Prima==0;
%         Ec6=I4Prima*app.AcelAngular4-ByB*app.L4*sin(app.cita4*(pi/180))-BxB*app.L4*cos(app.cita4*(pi/180))==0;
%         Ec7=-AxB-BxB-app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*cos(app.cita2*(pi/180))==0;
%         Ec8=-AyB-ByB-app.WAB.Value-app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*sin(app.cita2*(pi/180))==0;
%         Ec9=-app.WAB.Value*(app.Centroide3Ox-app.DAx)-ByB*(0.79198)-BxB*(2.8)==0;
%         
%         ecns = [Ec1,Ec2,Ec3,Ec4,Ec5,Ec6,Ec7,Ec8,Ec9];
%         SB = solve (ecns, [OxB,OyB,AxB,AyB,BxB,ByB,CxB,CyB,M2B]);
%         M2B=double(SB.M2B);
%         M2B=num2str(M2B);
%         app.MomentodeEntradaB.Value=M2B;
        
        %Termina parte de balance
        
        EsfuerzoOValueChanged(app, event)
        EsfuerzoAValueChanged(app, event)
        EsfuerzoBValueChanged(app, event)
        EsfuerzoCValueChanged(app, event)
        CortanteAValueChanged(app, event)
        CortanteOValueChanged(app, event)
        CortanteBValueChanged(app, event)
        CortanteCValueChanged(app, event)
        
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if isequal (value,'Vel. Ang (rpm)')
                app.VelocidadAngular.Visible= 'on';
                app.AcelAngular.Visible='off';
            else
                app.VelocidadAngular.Visible= 'off';
                app.AcelAngular.Visible='on';
                
            end
        end

        % Value changed function: AcelAngular
        function AcelAngularValueChanged(app, event)

        
        if isequal (app.AcelAngular.Visible,'on') 
        app.AcelAngular2= -1*app.AcelAngular.Value;
        app.AcelAngular4=app.AcelAngular2;
        app.w2=-1*sqrt(2*-1*app.AcelAngular2*(app.cita2*(pi/180))+(0)^2);
         app.w4=app.w2;
        else
            app.AcelAngular2=0;
            app.AcelAngular4=app.AcelAngular2;
            app.w2=-1*(app.VelocidadAngular.Value*((6.28/1)*(1/60)));
            app.w4=app.w2;
        end
            
        end

        % Value changed function: InerciaOA
        function InerciaOAValueChanged(app, event)
            value = app.InerciaOA.Value;
            app.inerciaOA=value;
            
        end

        % Value changed function: WOA
        function WOAValueChanged(app, event)
            app.WOA.Value=9.81*app.m2;
        end

        % Value changed function: WBote
        function WBoteValueChanged(app, event)
            app.WBote.Value=9.81*app.m;
            
        end

        % Value changed function: WAB
        function WABValueChanged(app, event)
            app.WAB.Value=9.81*app.m3;
        end

        % Value changed function: WBC
        function WBCValueChanged(app, event)
            app.WBC.Value=9.81*app.m4;
            
        end

        % Value changed function: EsfuerzoO
        function EsfuerzoOValueChanged(app, event)
            
            app.EsfuerzoO.Value=app.FOE/app.Atrans;
            
        end

        % Value changed function: EsfuerzoA
        function EsfuerzoAValueChanged(app, event)
            
            app.EsfuerzoA.Value=app.FA/app.Atrans;
            
        end

        % Value changed function: EsfuerzoB
        function EsfuerzoBValueChanged(app, event)
            
            app.EsfuerzoB.Value=app.FB/app.Atrans;
            
        end

        % Value changed function: EsfuerzoC
        function EsfuerzoCValueChanged(app, event)
            
            app.EsfuerzoC.Value=app.FCE/app.Atrans;
            
        end

        % Value changed function: CortanteO
        function CortanteOValueChanged(app, event)
            
            app.CortanteO.Value=app.FOE/app.Anorm;
        end

        % Value changed function: CortanteA
        function CortanteAValueChanged(app, event)
            
            app.CortanteA.Value=app.FA/app.Anorm;
        end

        % Value changed function: CortanteB
        function CortanteBValueChanged(app, event)
            
            app.CortanteB.Value=app.FB/app.Anorm;
        end

        % Value changed function: CortanteC
        function CortanteCValueChanged(app, event)
            
            app.CortanteC.Value=app.FCE/app.Anorm;
        end

        % Value changed function: Diseadopara4
        function Diseadopara4ValueChanged(app, event)
            
            
        end

        % Value changed function: error2
        function error2ValueChanged(app, event)
            app.error2.Value=(abs(app.InerciaOA.Value-app.IG2P.Value)/app.InerciaOA.Value)*100;
        end

        % Value changed function: error4
        function error4ValueChanged(app, event)
            
            app.error4.Value=(abs(app.InerciaBC.Value-app.IG4P.Value)/app.InerciaBC.Value)*100;
        end

        % Value changed function: error3
        function error3ValueChanged(app, event)
            
            app.error3.Value=(abs(app.InerciaAB.Value-app.IG3P.Value)/app.InerciaAB.Value)*100;
        end

        % Value changed function: IG2P
        function IG2PValueChanged(app, event)
            
            app.IG2P.Value=(app.mo.Value*(4.21)^2)+(app.mA2.Value*(4.21)^2);
        end

        % Value changed function: IG4P
        function IG4PValueChanged(app, event)
            
            app.IG4P.Value=(app.mc.Value*(4.21)^2)+(app.mB2.Value*(4.21)^2);
        end

        % Value changed function: IG3P
        function IG3PValueChanged(app, event)
            
            app.IG3P.Value=(app.mA3.Value*(4.2)^2)+(app.mB3.Value*(4.2)^2);
        end

        % Value changed function: mo
        function moValueChanged(app, event)
            syms mo mA2
            
             Eq1 = mo + mA2==app.m2;
             Eq2=mo*(4.21)==mA2*(4.21); 
             
             eqns = [Eq1,Eq2];
             S = solve (eqns, [mo,mA2]);
          
             app.mo.Value=double(S.mo);
             
             app.mA2.Value=double(S.mA2);
        end

        % Value changed function: mA3
        function mA3ValueChanged(app, event)
                 syms mB3 mA3
            
             Eq1 = mA3+mB3==app.m3;
             Eq2=mA3*(4.2)==mB3*(4.2); 
             
             eqns = [Eq1,Eq2];
             S = solve (eqns, [mA3,mB3]);
          
             app.mA3.Value=double(S.mA3);
             
             app.mB3.Value=double(S.mB3);
            
        end

        % Value changed function: mc
        function mcValueChanged(app, event)
                  syms mc mB2
            
             Eq1 = mc + mB2==app.m4;
             Eq2=mc*(4.21)==mB2*(4.21); 
             
             eqns = [Eq1,Eq2];
             S = solve (eqns, [mc,mB2]);
          
             app.mc.Value=double(S.mc);
             
             app.mB2.Value=double(S.mB2);
            
        end

        % Value changed function: InerciaAB
        function InerciaABValueChanged(app, event)
            value = app.InerciaAB.Value;
            
        end

        % Value changed function: InerciaBC
        function InerciaBCValueChanged(app, event)
            value = app.InerciaBC.Value;
            
        end

        % Callback function
        function Angulo3BValueChanged(app, event)
            app.Angulo2.Value=app.Angulo3B.Value;
             app.Angulo3.Value=app.Angulo3B.Value;
             app.Angulo1.Value=app.Angulo3B.Value;
             
             app.cita2=app.Angulo3B.Value;
             app.cita4=app.cita2;

        
        syms Ox Oy Ax Ay Bx By M2 Cx Cy OxB OyB AxB AyB BxB ByB M2B CxB CyB
        
        largoValueChanged(app, event)
        anchoValueChanged(app, event)
        pesoValueChanged(app, event)
        MaterialDropDownValueChanged(app, event)
        WOAValueChanged(app, event)
        WABValueChanged(app, event)
        WBCValueChanged(app, event)
        VelocidadAngularValueChanged(app, event)
        AcelAngularValueChanged(app, event)

        
        app.rho=str2double(app.MaterialDropDown.Value);
        app.L=app.largo.Value;
        app.Ancho=app.ancho.Value;
        app.m=app.peso.Value;
        
        app.Diseadopara1.Value=app.Usuario.Value;
        app.Diseadopara2.Value=app.Usuario.Value;
        app.Diseadopara3.Value=app.Usuario.Value;
        app.Diseadopara4.Value=app.Usuario.Value;
        
        app.DAx=app.L2*cos(app.cita2*(pi/180));
        app.DAy=app.L2*sin(app.cita2*(pi/180));
        
        app.Centroide3Ox=app.Centroide3Ax+app.DAx;
        app.Centroide3Oy=app.Centroide3Ay+app.DAy;
        
        app.VelCentro2x=-1*app.w2*app.Centroide2*sin(app.cita2*(pi/180));
        app.VelCentro2y=app.w2*app.Centroide2*cos(app.cita2*(pi/180));
        app.VelCentro4x=-1*app.w2*app.Centroide4*sin(app.cita4*(pi/180));
        app.VelCentro4y=app.w2*app.Centroide4*cos(app.cita4*(pi/180));
        app.VelCentro3x=-1*app.w2*app.Centroide4*sin(app.cita4*(pi/180));
        app.VelCentro3y=app.w2*app.Centroide4*cos(app.cita4*(pi/180));
        
        app.AcelCentro2x=app.AcelAngular2*sin(app.cita2*(pi/180))-(app.w2^2)*cos(app.cita2*(pi/180))*app.Centroide2;
        app.AcelCentro2y=-1*app.AcelAngular2*cos(app.cita2*(pi/180))-(app.w2^2)*sin(app.cita2*(pi/180))*app.Centroide2;
        app.AcelCentro4x=app.AcelAngular4*sin(app.cita4*(pi/180))-(app.w4^2)*cos(app.cita4*(pi/180))*app.Centroide4;
        app.AcelCentro4y=-1*app.AcelAngular4*cos(app.cita4*(pi/180))-(app.w4^2)*sin(app.cita4*(pi/180))*app.Centroide4;
        app.AcelCentro3x=app.AcelAngular2*sin(app.cita2*(pi/180))-(app.w2^2)*cos(app.cita2*(pi/180))*app.L2;
        app.AcelCentro3y=-1*app.AcelAngular2*cos(app.cita2*(pi/180))-(app.w2^2)*sin(app.cita2*(pi/180))*app.L2;
        
        Eq1 = Ox - Ay - app.m2*sqrt((app.AcelCentro2x)^2+(app.AcelCentro2y)^2)*cos(app.cita2*(pi/180))==0;
        Eq2= Oy + Ay - app.WOA.Value-app.m2*sqrt((app.AcelCentro2x)^2+(app.AcelCentro2y)^2)*sin(app.cita2*(pi/180))==0;
        Eq3= M2 - Oy* app.Centroide2*cos(app.cita2*(pi/180)) + Ox*app.Centroide2*sin(app.cita2*(pi/180))+ Ay*app.Centroide2*cos(app.cita2*(pi/180)) + Ax*app.Centroide2*sin(app.cita2*(pi/180)) - app.InerciaOA.Value* app.AcelAngular2 ==0;
        Eq4= -Bx + Cx - app.m4*sqrt((app.AcelCentro4x)^2+(app.AcelCentro4y)^2)*cos(app.cita4*(pi/180))==0;
        Eq5= -By + Cy - app.WBC.Value-app.m4*sqrt((app.AcelCentro4x)^2+(app.AcelCentro4y)^2)*sin(app.cita4*(pi/180))== 0;
        Eq6= Cx* app.Centroide4*sin(app.cita4*(pi/180)) + Cy*app.Centroide4*cos(app.cita4*(pi/180)) + Bx*app.Centroide4*sin(app.cita4*(pi/180)) - By*app.Centroide4*cos(app.cita4*(pi/180)) - app.InerciaBC.Value* app.AcelAngular4 ==0;
        Eq7= Ax + Bx - app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*cos(app.cita2*(pi/180)) == 0;
        Eq8=-Ay + By - app.WAB.Value -(app.WBote.Value/2) - app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*sin(app.cita2*(pi/180)) == 0;
        Eq9= -(app.WAB.Value + (app.WBote.Value/2))*(app.Centroide3Ox-app.DAx) + By *(0.79198) + Bx* (2.8)==0;
        
        eqns = [Eq1,Eq2,Eq3,Eq4,Eq5,Eq6,Eq7,Eq8,Eq9];
        S = solve (eqns, [Ox,Oy,Ax,Ay,Bx,By,Cx,Cy,M2]);
        M2=double(S.M2);
        M2=num2str(M2);
        app.MomentodeEntrada.Value=M2;
        
        Oy=double(S.Oy);
        app.Oy.Value=Oy;
        Ox=double(S.Ox);
        app.Ox.Value=Ox;
        
        Ax=double(S.Ax);
        app.Ax.Value=Ax;
        Ay=double(S.Ay);
        app.Ay.Value=Ay;
        
        Bx=double(S.Bx);
        app.Bx.Value=Bx;
        By=double(S.By);
        app.By.Value=By;
        
        Cx=double(S.Cx);
        app.Cx.Value=Cx;
        Cy=double(S.Cy);
        app.Cy.Value=Cy;
        
        app.FA=sqrt((Ax)^2+(Ay)^2);
        app.FB=sqrt((Bx)^2+(By)^2);
        app.FCE=sqrt((Cx)^2+(Cy)^2);
        app.FOE=sqrt((Ox)^2+(Oy)^2);
        
        %Inicia parte de Balance
        W2Prima=2*app.WOA.Value;
        W4Prima=2*app.WBC.Value;
        I2Prima=2*app.InerciaOA.Value;
        I4Prima=2*app.InerciaBC.Value;
        
        Ec1=AxB+OxB==0;
        Ec2=AyB+OyB-W2Prima==0;
        Ec3=M2B+AyB*app.L2*sin(app.cita2*(pi/180))-AxB*app.L2*cos(app.cita2*(pi/180))+I2Prima*app.AcelAngular2==0;
        Ec4=BxB+CxB==0;
        Ec5=ByB+CyB-W4Prima==0;
        Ec6=I4Prima*app.AcelAngular4-ByB*app.L4*sin(app.cita4*(pi/180))-BxB*app.L4*cos(app.cita4*(pi/180))==0;
        Ec7=-AxB-BxB-app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*cos(app.cita2*(pi/180))==0;
        Ec8=-AyB-ByB-app.WAB.Value-app.m3*sqrt((app.AcelCentro3x)^2+(app.AcelCentro3y)^2)*sin(app.cita2*(pi/180))==0;
        Ec9=-app.WAB.Value*(app.Centroide3Ox-app.DAx)-ByB*(0.79198)-BxB*(2.8)==0;
        
        ecns = [Ec1,Ec2,Ec3,Ec4,Ec5,Ec6,Ec7,Ec8,Ec9];
        SB = solve (ecns, [OxB,OyB,AxB,AyB,BxB,ByB,CxB,CyB,M2B]);
        M2B=double(SB.M2B);
        M2B=num2str(M2B);
        app.MomentodeEntradaB.Value=M2B;
        
        %Termina parte de balance
        
        EsfuerzoOValueChanged(app, event)
        EsfuerzoAValueChanged(app, event)
        EsfuerzoBValueChanged(app, event)
        EsfuerzoCValueChanged(app, event)
        CortanteAValueChanged(app, event)
        CortanteOValueChanged(app, event)
        CortanteBValueChanged(app, event)
        CortanteCValueChanged(app, event)
            
        end

        % Value changed function: MomentoEBalanceado
        function MomentoEBalanceadoValueChanged(app, event)
%             app.MomentoEBalanceado.Value= double(interp1(app.cita2x,app.MomentodeEntradaPlotB, 200));
% %             app.MomentodeEntradaPlotB,app.cita2x
            
        end

        % Value changed function: MasaTotalMecanismo
        function MasaTotalMecanismoValueChanged(app, event)
        W2Prima=2*app.WOA.Value;
        W4Prima=2*app.WBC.Value;
        I2Prima=2*app.InerciaOA.Value;
        I4Prima=2*app.InerciaBC.Value;
        
        app.MasaTotalMecanismo.Value=(W2Prima+W4Prima+app.WAB.Value)/9.81;
            
        end

        % Value changed function: CostodelMaterialColones
        function CostodelMaterialColonesValueChanged(app, event)
            app.CostodelMaterialColones.Value=app.MasaTotalMecanismo.Value*app.CostodelMaterialColKg.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatosTab = uitab(app.TabGroup);
            app.DatosTab.Title = 'Datos';
            app.GraciasporinteresarseenadquirirnuestroproductoLabel = uilabel(app.DatosTab);
            app.GraciasporinteresarseenadquirirnuestroproductoLabel.Text = 'Gracias por interesarse en adquirir nuestro producto. ';
            app.Image = uiimage(app.DatosTab);
            app.Image.ImageSource = '2.PNG';
            app.Label = uilabel(app.DatosTab);
            app.Label.Text = 'A continuación se le solicitaran algunos datos que nos permitiran adecuarnos a sus necesidades de';
            app.maneramsefectivaPorfavorpodradecirnosLabel = uilabel(app.DatosTab);
            app.maneramsefectivaPorfavorpodradecirnosLabel.Text = ' manera más efectiva. Por favor, podría decirnos...';
            app.LargodelBotemEditFieldLabel = uilabel(app.DatosTab);
            app.LargodelBotemEditFieldLabel.Text = 'Largo del Bote (m):';
            app.largo = uieditfield(app.DatosTab, 'numeric');
            app.largo.Limits = [0 10];
            app.largo.ValueChangedFcn = createCallbackFcn(app, @largoValueChanged, true);
            app.AnchodelBotemEditFieldLabel = uilabel(app.DatosTab);
            app.AnchodelBotemEditFieldLabel.Text = 'Ancho del Bote (m):';
            app.ancho = uieditfield(app.DatosTab, 'numeric');
            app.ancho.Limits = [0 10];
            app.ancho.ValueChangedFcn = createCallbackFcn(app, @anchoValueChanged, true);
            app.NombredelClienteLabel = uilabel(app.DatosTab);
            app.NombredelClienteLabel.Text = 'Nombre del Cliente:';
            app.Usuario = uieditfield(app.DatosTab, 'text');
            app.Usuario.ValueChangedFcn = createCallbackFcn(app, @UsuarioValueChanged, true);
            app.PesodelBotekgEditFieldLabel = uilabel(app.DatosTab);
            app.PesodelBotekgEditFieldLabel.Text = 'Peso del Bote (kg):';
            app.peso = uieditfield(app.DatosTab, 'numeric');
            app.peso.Limits = [0 Inf];
            app.peso.ValueChangedFcn = createCallbackFcn(app, @pesoValueChanged, true);
            app.VelocidadAngular = uiknob(app.DatosTab, 'continuous');
            app.VelocidadAngular.Limits = [0.5 4];
            app.VelocidadAngular.ValueChangedFcn = createCallbackFcn(app, @VelocidadAngularValueChanged, true);
            app.VelocidadAngular.Value = 0.5;
            app.MaterialLabel = uilabel(app.DatosTab);
            app.MaterialLabel.Text = '    Material          ';
            app.MaterialDropDown = uidropdown(app.DatosTab);
            app.MaterialDropDown.Items = {'Seleccione', 'Roble', 'Pino', 'Latón', 'Acero', 'Aluminio'};
            app.MaterialDropDown.ItemsData = {'0', '560', '350', '8500', '7900', '2700', '', ''};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = '0';
            app.delLabel = uilabel(app.DatosTab);
            app.delLabel.Text = 'del';
            app.MecanismoLabel = uilabel(app.DatosTab);
            app.MecanismoLabel.Text = 'Mecanismo';
            app.DisearmiMecanismoButton = uibutton(app.DatosTab, 'push');
            app.DisearmiMecanismoButton.ButtonPushedFcn = createCallbackFcn(app, @DisearmiMecanismoButtonPushed, true);
            app.DisearmiMecanismoButton.Text = 'Diseñar mi Mecanismo';
            app.Switch = uiswitch(app.DatosTab, 'toggle');
            app.Switch.Items = {'Vel. Ang (rpm)', 'Acel. Ang (rad/s2)'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Vel. Ang (rpm)';
            app.AcelAngular = uiknob(app.DatosTab, 'continuous');
            app.AcelAngular.Limits = [1 5];
            app.AcelAngular.ValueChangedFcn = createCallbackFcn(app, @AcelAngularValueChanged, true);
            app.AcelAngular.Visible = 'off';
            app.AcelAngular.Value = 1;
            app.MecanismoTab = uitab(app.TabGroup);
            app.MecanismoTab.Title = 'Mecanismo';
            app.Image2 = uiimage(app.MecanismoTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'BoteEnAgua.PNG';
            app.Image3 = uiimage(app.MecanismoTab);
            app.Image3.ImageSource = 'Solid.png';
            app.Image4 = uiimage(app.MecanismoTab);
            app.Image4.ImageSource = 'Referencias.png';
            app.MaquetadelMecanismoLabel = uilabel(app.MecanismoTab);
            app.MaquetadelMecanismoLabel.Text = 'Maqueta del Mecanismo';
            app.SimulacindelMecanismoLabel = uilabel(app.MecanismoTab);
            app.SimulacindelMecanismoLabel.Text = 'Simulación del Mecanismo';
            app.SealamientosdereferenciaLabel = uilabel(app.MecanismoTab);
            app.SealamientosdereferenciaLabel.Text = 'Señalamientos de referencia';
            app.SetratadedosmecanismosTipo2quetransportanLabel = uilabel(app.MecanismoTab);
            app.SetratadedosmecanismosTipo2quetransportanLabel.Text = 'Se trata de dos mecanismos Tipo 2, que transportan ';
            app.A175deentradaeleslabon3estaenelmuelleLabel = uilabel(app.MecanismoTab);
            app.A175deentradaeleslabon3estaenelmuelleLabel.Text = 'A 175º de entrada el eslabon 3 esta en el muelle.';
            app.A350deentradaeleslabon3estaenelaguaLabel = uilabel(app.MecanismoTab);
            app.A350deentradaeleslabon3estaenelaguaLabel.Text = 'A 350º de entrada el eslabon 3 esta en el agua.';
            app.unbotedesdeelmuellehastaelaguaLabel = uilabel(app.MecanismoTab);
            app.unbotedesdeelmuellehastaelaguaLabel.Text = 'un bote desde el muelle hasta el agua. ';
            app.IG2kgm2Label = uilabel(app.MecanismoTab);
            app.IG2kgm2Label.Text = 'IG2 (kgm2)';
            app.InerciaOA = uieditfield(app.MecanismoTab, 'numeric');
            app.InerciaOA.ValueChangedFcn = createCallbackFcn(app, @InerciaOAValueChanged, true);
            app.InerciaOA.Editable = 'off';
            app.IG3kgm2Label = uilabel(app.MecanismoTab);
            app.IG3kgm2Label.Text = 'IG3 (kgm2)';
            app.InerciaAB = uieditfield(app.MecanismoTab, 'numeric');
            app.InerciaAB.ValueChangedFcn = createCallbackFcn(app, @InerciaABValueChanged, true);
            app.InerciaAB.Editable = 'off';
            app.IG4kgm2Label = uilabel(app.MecanismoTab);
            app.IG4kgm2Label.Text = 'IG4 (kgm2)';
            app.InerciaBC = uieditfield(app.MecanismoTab, 'numeric');
            app.InerciaBC.ValueChangedFcn = createCallbackFcn(app, @InerciaBCValueChanged, true);
            app.InerciaBC.Editable = 'off';
            app.FuerzasTab = uitab(app.TabGroup);
            app.FuerzasTab.Title = 'Fuerzas';
            app.DiseadoparaEditFieldLabel = uilabel(app.FuerzasTab);
            app.DiseadoparaEditFieldLabel.Text = 'Diseñado para:';
            app.Diseadopara1 = uieditfield(app.FuerzasTab, 'text');
            app.Diseadopara1.Editable = 'off';
            app.AngulodeSpinnerLabel = uilabel(app.FuerzasTab);
            app.AngulodeSpinnerLabel.Text = 'Angulo de';
            app.Angulo1 = uispinner(app.FuerzasTab);
            app.Angulo1.Limits = [175 350];
            app.Angulo1.ValueChangedFcn = createCallbackFcn(app, @Angulo1ValueChanged, true);
            app.Angulo1.Value = 175;
            app.EntradaLabel = uilabel(app.FuerzasTab);
            app.EntradaLabel.Text = 'Entrada (º)';
            app.WBoteNEditFieldLabel = uilabel(app.FuerzasTab);
            app.WBoteNEditFieldLabel.Text = 'W Bote (N)';
            app.WBote = uieditfield(app.FuerzasTab, 'numeric');
            app.WBote.ValueChangedFcn = createCallbackFcn(app, @WBoteValueChanged, true);
            app.WBote.Editable = 'off';
            app.WOANLabel = uilabel(app.FuerzasTab);
            app.WOANLabel.Text = 'W OA (N)';
            app.WOA = uieditfield(app.FuerzasTab, 'numeric');
            app.WOA.ValueChangedFcn = createCallbackFcn(app, @WOAValueChanged, true);
            app.WOA.Editable = 'off';
            app.WABNLabel = uilabel(app.FuerzasTab);
            app.WABNLabel.Text = 'W AB (N)';
            app.WAB = uieditfield(app.FuerzasTab, 'numeric');
            app.WAB.ValueChangedFcn = createCallbackFcn(app, @WABValueChanged, true);
            app.WAB.Editable = 'off';
            app.WBCNLabel = uilabel(app.FuerzasTab);
            app.WBCNLabel.Text = 'W BC (N)';
            app.WBC = uieditfield(app.FuerzasTab, 'numeric');
            app.WBC.ValueChangedFcn = createCallbackFcn(app, @WBCValueChanged, true);
            app.WBC.Editable = 'off';
            app.OyNLabel = uilabel(app.FuerzasTab);
            app.OyNLabel.Text = 'Oy (N)';
            app.Oy = uieditfield(app.FuerzasTab, 'numeric');
            app.Oy.Editable = 'off';
            app.OxNLabel = uilabel(app.FuerzasTab);
            app.OxNLabel.Text = 'Ox (N)';
            app.Ox = uieditfield(app.FuerzasTab, 'numeric');
            app.Ox.Editable = 'off';
            app.ByNLabel = uilabel(app.FuerzasTab);
            app.ByNLabel.Text = 'By (N)';
            app.By = uieditfield(app.FuerzasTab, 'numeric');
            app.By.Editable = 'off';
            app.BxNLabel = uilabel(app.FuerzasTab);
            app.BxNLabel.Text = 'Bx (N)';
            app.Bx = uieditfield(app.FuerzasTab, 'numeric');
            app.Bx.Editable = 'off';
            app.AyNLabel = uilabel(app.FuerzasTab);
            app.AyNLabel.Text = 'Ay (N)';
            app.Ay = uieditfield(app.FuerzasTab, 'numeric');
            app.Ay.Editable = 'off';
            app.AxNLabel = uilabel(app.FuerzasTab);
            app.AxNLabel.Text = 'Ax (N)';
            app.Ax = uieditfield(app.FuerzasTab, 'numeric');
            app.Ax.Editable = 'off';
            app.CyNLabel = uilabel(app.FuerzasTab);
            app.CyNLabel.Text = 'Cy (N)';
            app.Cy = uieditfield(app.FuerzasTab, 'numeric');
            app.Cy.Editable = 'off';
            app.CxNLabel = uilabel(app.FuerzasTab);
            app.CxNLabel.Text = 'Cx (N)';
            app.Cx = uieditfield(app.FuerzasTab, 'numeric');
            app.Cx.Editable = 'off';
            app.EsfuerzosTab = uitab(app.TabGroup);
            app.EsfuerzosTab.Title = 'Esfuerzos';
            app.DiseadoparaEditField_2Label = uilabel(app.EsfuerzosTab);
            app.DiseadoparaEditField_2Label.Text = 'Diseñado para:';
            app.Diseadopara2 = uieditfield(app.EsfuerzosTab, 'text');
            app.Diseadopara2.Editable = 'off';
            app.AngulodeSpinner_2Label = uilabel(app.EsfuerzosTab);
            app.AngulodeSpinner_2Label.Text = 'Angulo de';
            app.Angulo2 = uispinner(app.EsfuerzosTab);
            app.Angulo2.Limits = [175 350];
            app.Angulo2.ValueChangedFcn = createCallbackFcn(app, @Angulo2ValueChanged, true);
            app.Angulo2.Value = 175;
            app.EntradaLabel_2 = uilabel(app.EsfuerzosTab);
            app.EntradaLabel_2.Text = 'Entrada (º)';
            app.EsfuerzoOPaLabel = uilabel(app.EsfuerzosTab);
            app.EsfuerzoOPaLabel.Text = 'Esfuerzo O (Pa)';
            app.EsfuerzoO = uieditfield(app.EsfuerzosTab, 'numeric');
            app.EsfuerzoO.ValueChangedFcn = createCallbackFcn(app, @EsfuerzoOValueChanged, true);
            app.EsfuerzoO.Editable = 'off';
            app.EsfuerzoAPaLabel = uilabel(app.EsfuerzosTab);
            app.EsfuerzoAPaLabel.Text = 'Esfuerzo A (Pa)';
            app.EsfuerzoA = uieditfield(app.EsfuerzosTab, 'numeric');
            app.EsfuerzoA.ValueChangedFcn = createCallbackFcn(app, @EsfuerzoAValueChanged, true);
            app.EsfuerzoA.Editable = 'off';
            app.EsfuerzoBPaLabel = uilabel(app.EsfuerzosTab);
            app.EsfuerzoBPaLabel.Text = 'Esfuerzo B (Pa)';
            app.EsfuerzoB = uieditfield(app.EsfuerzosTab, 'numeric');
            app.EsfuerzoB.ValueChangedFcn = createCallbackFcn(app, @EsfuerzoBValueChanged, true);
            app.EsfuerzoB.Editable = 'off';
            app.EsfuerzoCPaLabel = uilabel(app.EsfuerzosTab);
            app.EsfuerzoCPaLabel.Text = 'Esfuerzo C (Pa)';
            app.EsfuerzoC = uieditfield(app.EsfuerzosTab, 'numeric');
            app.EsfuerzoC.ValueChangedFcn = createCallbackFcn(app, @EsfuerzoCValueChanged, true);
            app.EsfuerzoC.Editable = 'off';
            app.CortanteOPaEditFieldLabel = uilabel(app.EsfuerzosTab);
            app.CortanteOPaEditFieldLabel.Text = 'Cortante O (Pa)';
            app.CortanteO = uieditfield(app.EsfuerzosTab, 'numeric');
            app.CortanteO.ValueChangedFcn = createCallbackFcn(app, @CortanteOValueChanged, true);
            app.CortanteO.Editable = 'off';
            app.CortanteAPaEditFieldLabel = uilabel(app.EsfuerzosTab);
            app.CortanteAPaEditFieldLabel.Text = 'Cortante A (Pa)';
            app.CortanteA = uieditfield(app.EsfuerzosTab, 'numeric');
            app.CortanteA.ValueChangedFcn = createCallbackFcn(app, @CortanteAValueChanged, true);
            app.CortanteA.Editable = 'off';
            app.CortanteBPaEditFieldLabel = uilabel(app.EsfuerzosTab);
            app.CortanteBPaEditFieldLabel.Text = 'Cortante B (Pa)';
            app.CortanteB = uieditfield(app.EsfuerzosTab, 'numeric');
            app.CortanteB.ValueChangedFcn = createCallbackFcn(app, @CortanteBValueChanged, true);
            app.CortanteB.Editable = 'off';
            app.CortanteCPaEditFieldLabel = uilabel(app.EsfuerzosTab);
            app.CortanteCPaEditFieldLabel.Text = 'Cortante C (Pa)';
            app.CortanteC = uieditfield(app.EsfuerzosTab, 'numeric');
            app.CortanteC.ValueChangedFcn = createCallbackFcn(app, @CortanteCValueChanged, true);
            app.CortanteC.Editable = 'off';
            app.MomentoTab = uitab(app.TabGroup);
            app.MomentoTab.Title = 'Momento';
            app.DiseadoparaEditField_3Label = uilabel(app.MomentoTab);
            app.DiseadoparaEditField_3Label.Text = 'Diseñado para:';
            app.Diseadopara3 = uieditfield(app.MomentoTab, 'text');
            app.Diseadopara3.Editable = 'off';
            app.AngulodeSpinner_3Label = uilabel(app.MomentoTab);
            app.AngulodeSpinner_3Label.Text = 'Angulo de';
            app.Angulo3 = uispinner(app.MomentoTab);
            app.Angulo3.Limits = [175 350];
            app.Angulo3.ValueChangedFcn = createCallbackFcn(app, @Angulo3ValueChanged, true);
            app.Angulo3.Value = 175;
            app.EntradaLabel_3 = uilabel(app.MomentoTab);
            app.EntradaLabel_3.Text = 'Entrada (º)';
            app.MomentodeEditFieldLabel = uilabel(app.MomentoTab);
            app.MomentodeEditFieldLabel.Text = 'Momento de ';
            app.MomentodeEntrada = uieditfield(app.MomentoTab, 'text');
            app.MomentodeEntrada.Editable = 'off';
            app.EntradaNmLabel = uilabel(app.MomentoTab);
            app.EntradaNmLabel.Text = 'Entrada (Nm)';
            app.MomentodeEntradaPlot = uiaxes(app.MomentoTab);
            title(app.MomentodeEntradaPlot, 'Momento de Entrada en funcion del Angulo')
            xlabel(app.MomentodeEntradaPlot, 'Angulo de Entrada (º)')
            ylabel(app.MomentodeEntradaPlot, 'Momento de Entrada (Nm)')
            app.FSacudimientoTab = uitab(app.TabGroup);
            app.FSacudimientoTab.Title = 'F. Sacudimiento';
            app.FO = uiaxes(app.FSacudimientoTab);
            title(app.FO, 'Fuerzas en Punto O')
            xlabel(app.FO, 'Angulo de Entrada (º)')
            ylabel(app.FO, 'Fuerzas en O (N)')
            app.FC = uiaxes(app.FSacudimientoTab);
            title(app.FC, 'Fuerzas en Punto C')
            xlabel(app.FC, 'Angulo de Entrada (º)')
            ylabel(app.FC, 'Fuerzas en C (N)')
            app.DiseadoparaEditField_4Label = uilabel(app.FSacudimientoTab);
            app.DiseadoparaEditField_4Label.Text = 'Diseñado para:';
            app.Diseadopara4 = uieditfield(app.FSacudimientoTab, 'text');
            app.Diseadopara4.ValueChangedFcn = createCallbackFcn(app, @Diseadopara4ValueChanged, true);
            app.Diseadopara4.Editable = 'off';
            app.SimplificacinTab = uitab(app.TabGroup);
            app.SimplificacinTab.Title = 'Simplificación';
            app.DiseadoparaEditField_4Label_2 = uilabel(app.SimplificacinTab);
            app.DiseadoparaEditField_4Label_2.Text = 'Diseñado para:';
            app.Diseadopara5 = uieditfield(app.SimplificacinTab, 'text');
            app.Diseadopara5.Editable = 'off';
            app.Image5 = uiimage(app.SimplificacinTab);
            app.Image5.ImageSource = 'simp.png';
            app.Eslabon2Label = uilabel(app.SimplificacinTab);
            app.Eslabon2Label.Text = 'Eslabon 2';
            app.mokgEditFieldLabel = uilabel(app.SimplificacinTab);
            app.mokgEditFieldLabel.Text = 'mo (kg)';
            app.mo = uieditfield(app.SimplificacinTab, 'numeric');
            app.mo.ValueChangedFcn = createCallbackFcn(app, @moValueChanged, true);
            app.mA2kgEditFieldLabel = uilabel(app.SimplificacinTab);
            app.mA2kgEditFieldLabel.Text = 'mA2 (kg)';
            app.mA2 = uieditfield(app.SimplificacinTab, 'numeric');
            app.IG2kgm2EditFieldLabel = uilabel(app.SimplificacinTab);
            app.IG2kgm2EditFieldLabel.Text = 'IG2'' (kgm2)';
            app.IG2P = uieditfield(app.SimplificacinTab, 'numeric');
            app.IG2P.ValueChangedFcn = createCallbackFcn(app, @IG2PValueChanged, true);
            app.errorEditFieldLabel = uilabel(app.SimplificacinTab);
            app.errorEditFieldLabel.Text = '% error';
            app.error2 = uieditfield(app.SimplificacinTab, 'numeric');
            app.error2.ValueChangedFcn = createCallbackFcn(app, @error2ValueChanged, true);
            app.Eslabon3Label = uilabel(app.SimplificacinTab);
            app.Eslabon3Label.Text = 'Eslabon 3';
            app.mA3kgLabel = uilabel(app.SimplificacinTab);
            app.mA3kgLabel.Text = 'mA3 (kg)';
            app.mA3 = uieditfield(app.SimplificacinTab, 'numeric');
            app.mA3.ValueChangedFcn = createCallbackFcn(app, @mA3ValueChanged, true);
            app.mB3kgLabel = uilabel(app.SimplificacinTab);
            app.mB3kgLabel.Text = 'mB3 (kg)';
            app.mB3 = uieditfield(app.SimplificacinTab, 'numeric');
            app.IG3kgm2Label_2 = uilabel(app.SimplificacinTab);
            app.IG3kgm2Label_2.Text = 'IG3'' (kgm2)';
            app.IG3P = uieditfield(app.SimplificacinTab, 'numeric');
            app.IG3P.ValueChangedFcn = createCallbackFcn(app, @IG3PValueChanged, true);
            app.errorEditFieldLabel_2 = uilabel(app.SimplificacinTab);
            app.errorEditFieldLabel_2.Text = '% error';
            app.error3 = uieditfield(app.SimplificacinTab, 'numeric');
            app.error3.ValueChangedFcn = createCallbackFcn(app, @error3ValueChanged, true);
            app.Eslabon4Label = uilabel(app.SimplificacinTab);
            app.Eslabon4Label.Text = 'Eslabon 4';
            app.mckgLabel = uilabel(app.SimplificacinTab);
            app.mckgLabel.Text = 'mc (kg)';
            app.mc = uieditfield(app.SimplificacinTab, 'numeric');
            app.mc.ValueChangedFcn = createCallbackFcn(app, @mcValueChanged, true);
            app.mB2kgLabel = uilabel(app.SimplificacinTab);
            app.mB2kgLabel.Text = 'mB2 (kg)';
            app.mB2 = uieditfield(app.SimplificacinTab, 'numeric');
            app.IG4kgm2Label_2 = uilabel(app.SimplificacinTab);
            app.IG4kgm2Label_2.Text = 'IG4'' (kgm2)';
            app.IG4P = uieditfield(app.SimplificacinTab, 'numeric');
            app.IG4P.ValueChangedFcn = createCallbackFcn(app, @IG4PValueChanged, true);
            app.errorEditFieldLabel_3 = uilabel(app.SimplificacinTab);
            app.errorEditFieldLabel_3.Text = '% error';
            app.error4 = uieditfield(app.SimplificacinTab, 'numeric');
            app.error4.ValueChangedFcn = createCallbackFcn(app, @error4ValueChanged, true);
            app.FSacBalancTab = uitab(app.TabGroup);
            app.FSacBalancTab.Title = 'F. Sac Balanc';
            app.DiseadoparaEditField_4Label_4 = uilabel(app.FSacBalancTab);
            app.DiseadoparaEditField_4Label_4.Text = 'Diseñado para:';
            app.Diseadopara7 = uieditfield(app.FSacBalancTab, 'text');
            app.Diseadopara7.Editable = 'off';
            app.FOB = uiaxes(app.FSacBalancTab);
            title(app.FOB, 'Fuerzas en Punto O (Con Balance)')
            xlabel(app.FOB, 'Angulo de Entrada (º)')
            ylabel(app.FOB, 'Fuerzas en O (N)')
            app.FCB = uiaxes(app.FSacBalancTab);
            title(app.FCB, 'Fuerzas en Punto C (Con Balance)')
            xlabel(app.FCB, 'Angulo de Entrada (º)')
            ylabel(app.FCB, 'Fuerzas en C (N)')
            app.MomentoBalancTab = uitab(app.TabGroup);
            app.MomentoBalancTab.Title = 'Momento Balanc';
            app.DiseadoparaEditField_4Label_5 = uilabel(app.MomentoBalancTab);
            app.DiseadoparaEditField_4Label_5.Text = 'Diseñado para:';
            app.Diseadopara8 = uieditfield(app.MomentoBalancTab, 'text');
            app.Diseadopara8.Editable = 'off';
            app.MomentodeEntradaPlotB = uiaxes(app.MomentoBalancTab);
            title(app.MomentodeEntradaPlotB, 'Momento de Entrada en funcion del Angulo (Con Balance)')
            xlabel(app.MomentodeEntradaPlotB, 'Angulo de Entrada (º)')
            ylabel(app.MomentodeEntradaPlotB, 'Momento de Entrada (Nm)')
            app.MomentoEditFieldLabel = uilabel(app.MomentoBalancTab);
            app.MomentoEditFieldLabel.Visible = 'off';
            app.MomentoEditFieldLabel.Text = 'Momento';
            app.MomentoEBalanceado = uieditfield(app.MomentoBalancTab, 'numeric');
            app.MomentoEBalanceado.ValueChangedFcn = createCallbackFcn(app, @MomentoEBalanceadoValueChanged, true);
            app.MomentoEBalanceado.Visible = 'off';
            app.deLabel = uilabel(app.MomentoBalancTab);
            app.deLabel.Visible = 'off';
            app.deLabel.Text = 'de';
            app.EntradaLabel_4 = uilabel(app.MomentoBalancTab);
            app.EntradaLabel_4.Visible = 'off';
            app.EntradaLabel_4.Text = 'Entrada';
            app.BalanceadoLabel = uilabel(app.MomentoBalancTab);
            app.BalanceadoLabel.Visible = 'off';
            app.BalanceadoLabel.Text = '(Balanceado)';
            app.CostoTab = uitab(app.TabGroup);
            app.CostoTab.Title = 'Costo';
            app.DiseadoparaEditField_4Label_6 = uilabel(app.CostoTab);
            app.DiseadoparaEditField_4Label_6.Text = 'Diseñado para:';
            app.Diseadopara9 = uieditfield(app.CostoTab, 'text');
            app.Diseadopara9.Editable = 'off';
            app.MasaTotalEditFieldLabel = uilabel(app.CostoTab);
            app.MasaTotalEditFieldLabel.Text = 'Masa Total';
            app.MasaTotalMecanismo = uieditfield(app.CostoTab, 'numeric');
            app.MasaTotalMecanismo.ValueChangedFcn = createCallbackFcn(app, @MasaTotalMecanismoValueChanged, true);
            app.MasaTotalMecanismo.Editable = 'off';
            app.delmaterialLabel = uilabel(app.CostoTab);
            app.delmaterialLabel.Text = 'del material';
            app.paraelLabel = uilabel(app.CostoTab);
            app.paraelLabel.Text = 'para el';
            app.mecanismoLabel = uilabel(app.CostoTab);
            app.mecanismoLabel.Text = 'mecanismo';
            app.CostodelMaterialEditFieldLabel = uilabel(app.CostoTab);
            app.CostodelMaterialEditFieldLabel.Text = 'Costo del Material';
            app.CostodelMaterialColones = uieditfield(app.CostoTab, 'numeric');
            app.CostodelMaterialColones.ValueChangedFcn = createCallbackFcn(app, @CostodelMaterialColonesValueChanged, true);
            app.CostodelMaterialColones.Editable = 'off';
            app.delLabel_2 = uilabel(app.CostoTab);
            app.delLabel_2.Text = 'del';
            app.MecanismoLabel_2 = uilabel(app.CostoTab);
            app.MecanismoLabel_2.Text = 'Mecanismo';
            app.ColonesLabel = uilabel(app.CostoTab);
            app.ColonesLabel.Text = '(Colones)';
            app.delLabel_3 = uilabel(app.CostoTab);
            app.delLabel_3.Text = 'del';
            app.MecanismoLabel_3 = uilabel(app.CostoTab);
            app.MecanismoLabel_3.Text = 'Mecanismo';
            app.ColoneskgLabel = uilabel(app.CostoTab);
            app.ColoneskgLabel.Text = '(Colones/kg)';
            app.CostodelMaterialEditField_2Label = uilabel(app.CostoTab);
            app.CostodelMaterialEditField_2Label.Text = 'Costo del Material';
            app.CostodelMaterialColKg = uieditfield(app.CostoTab, 'numeric');
            app.CostodelMaterialColKg.Editable = 'off';
            app.BoaTico2GoTab = uitab(app.TabGroup);
            app.BoaTico2GoTab.Title = 'BoaTico2Go';
            app.Image_2 = uiimage(app.BoaTico2GoTab);
            app.Image_2.ImageSource = '2.PNG';
            app.Label_2 = uilabel(app.BoaTico2GoTab);
            app.Label_2.Text = 'BoaTico2Go es una empresa lider en el tranporte de botes. Diseñamos tanto para agua dulce ';
            app.comoparasaladasegneltamaodelbotequeustednecesiteLabel = uilabel(app.BoaTico2GoTab);
            app.comoparasaladasegneltamaodelbotequeustednecesiteLabel.Text = 'como para salada, según el tamaño del bote que usted necesite.';
            app.Label_3 = uilabel(app.BoaTico2GoTab);
            app.Label_3.Text = 'Gran variedad de materiales, seleccionados para brindar una larga vida útil.';
            app.ParacualquierconsultacontactenosEstamosparaservirleLabel = uilabel(app.BoaTico2GoTab);
            app.ParacualquierconsultacontactenosEstamosparaservirleLabel.Text = 'Para cualquier consulta, contactenos. Estamos para servirle.';
            app.boatico2goNoExistecomLabel = uilabel(app.BoaTico2GoTab);
            app.boatico2goNoExistecomLabel.Text = 'boatico2go@NoExiste.com';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoaTico2Go

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end